classdef Cell_overlap_V1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BatchProcessPanel              matlab.ui.container.Panel
        SaveallButton                  matlab.ui.control.Button
        SkipButton                     matlab.ui.control.Button
        RandomizeCheckBox              matlab.ui.control.CheckBox
        QuitButton                     matlab.ui.control.Button
        SaveNextButton                 matlab.ui.control.Button
        BatchImportButton              matlab.ui.control.Button
        ImageprocessingPanel           matlab.ui.container.Panel
        ManualROIToolsPanel            matlab.ui.container.Panel
        ToolsizeSlider                 matlab.ui.control.Slider
        ToolsizeSliderLabel            matlab.ui.control.Label
        DeleteButton                   matlab.ui.control.Button
        EraserButton                   matlab.ui.control.Button
        BrushButton                    matlab.ui.control.Button
        OverlapSlider                  matlab.ui.control.Slider
        OverlapSliderLabel             matlab.ui.control.Label
        WatershedSlider                matlab.ui.control.Slider
        WatershedLabel                 matlab.ui.control.Label
        AutoCheckBox_Usewatershed      matlab.ui.control.CheckBox
        SelectedChannelDropDown        matlab.ui.control.DropDown
        SelectedChannelDropDownLabel   matlab.ui.control.Label
        ErosionSlider_2                matlab.ui.control.Slider
        ErosionSlider_2Label           matlab.ui.control.Label
        DilationSlider_2               matlab.ui.control.Slider
        DilationSlider_2Label          matlab.ui.control.Label
        CellSizeSlider                 matlab.ui.control.RangeSlider
        CellSizeSliderLabel            matlab.ui.control.Label
        AutoCheckBox_MaskThreshold     matlab.ui.control.CheckBox
        MaskThresholdSlider_2          matlab.ui.control.Slider
        MaskThresholdSlider_2Label     matlab.ui.control.Label
        ImageintensitySlider_2         matlab.ui.control.Slider
        ImageintensitySlider_2Label    matlab.ui.control.Label
        AutoCheckBox_ImageIntensity    matlab.ui.control.CheckBox
        SelectedChanneltoComparePanel  matlab.ui.container.Panel
        ChannelstocompareDropDown_2    matlab.ui.control.DropDown
        ChannelstocompareDropDown      matlab.ui.control.DropDown
        FileandConfigSettingPanel      matlab.ui.container.Panel
        EditFieldm2pixelUnit           matlab.ui.control.NumericEditField
        mpixelEditFieldLabel           matlab.ui.control.Label
        RatioLabel                     matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        ConfigLabel                    matlab.ui.control.Label
        SingleFileLabel                matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        img            % HxWxC double [0..1]
        meta           % struct (filename, size, etc.)
        mask           % cell{C} of logical HxW
        params         % struct of tunables
        % Per-channel persistent state (created after import)
        chan            % 1xC struct array: chan(c).intensity, chan(c).seg
        selectedChan = 1; % channel you are editing via the controls (1..C)
        editMode   char = 'none';    % 'brush' | 'eraser' | 'delete' | 'none'
        strokePrev matlab.graphics.primitive.Image = matlab.graphics.primitive.Image.empty; % optional preview overlay
        isDrawing   logical = false;                 % currently drawing?
        drawTool    char    = 'none';                % 'brush' | 'eraser' | 'delete' | 'none'
        drawPts     double  = zeros(0,2);            % Nx2 [x y] path in axes coords
        % drawLine    matlab.graphics.chart.primitive.Line = ...
        %     matlab.graphics.chart.primitive.Line.empty;   % preview polyline
        drawLine = gobjects(0);
        % ===== BATCH PROCESSING STATE =====
        batch = struct( ...
            'files',   {{}}, ...   % cellstr of absolute .tif/.tiff paths
            'idx',     0,    ...   % current 1-based index
            'root',    '',   ...   % folder of first selected file
            'summary', table(), ...% accumulating per-image summary rows
            'running', false);     % true when a batch session is active
    end

    methods (Access = private)

        function initParams(app)
            % Channels
            app.params.channels.active = [1 2];

            % Segmentation
            app.params.seg.manualLvl   = 0.5;
            app.params.seg.adaptiveWin = 51;
            app.params.seg.minArea     = 30;
            app.params.seg.maxArea     = 2000;
            app.params.seg.erode       = 0;
            app.params.seg.dilate      = 0;

            % Calibration
            app.params.px_per_um = []; % set from µm/pixel when provided
        end

        function onImport(app)
            [f,p] = uigetfile({'*.tif;*.tiff','TIFF images'}, 'Select image');
            if isequal(f,0), return; end
            app.local_load_file(fullfile(p,f), false);  % ← preserveState = false
        end


        function applySegmentation(app)
            if isempty(app.img), return; end

            % --- read UI state for the two channels to compare ---
            c1 = sscanf(app.ChannelstocompareDropDown.Value,   'Ch %d'); if isempty(c1), c1 = 1; end
            c2 = sscanf(app.ChannelstocompareDropDown_2.Value, 'Ch %d'); if isempty(c2), c2 = min(2,size(app.img,3)); end
            app.params.channels.active = [c1 c2];

            % (Optional: if you still set app.params.seg from sliders elsewhere, it
            % won’t hurt, but we read per-channel settings from app.chan(c).seg below.)

            % Cache availability of optional ops once
            hasOpThreshold = ~isempty(which('op_threshold'));
            hasSizeFilter  = ~isempty(which('op_sizeFilter'));
            hasMorph       = ~isempty(which('op_morph'));
           
            % --- compute per active channel only ---
            chs = app.params.channels.active;
            for k = 1:numel(chs)
                c   = chs(k);
                seg = app.chan(c).seg;          % per-channel segmentation settings
                I   = app.img(:,:,c);

                % Thresholding (unchanged) ...
                if seg.auto
                    if hasOpThreshold
                        BW = op_threshold(I, 'adaptive', seg.adaptiveWin);
                    else
                        BW = app.local_op_threshold(I, 'adaptive', seg.adaptiveWin);
                    end
                else
                    if hasOpThreshold
                        BW = op_threshold(I, 'manual', seg.manualLvl);
                    else
                        BW = app.local_op_threshold(I, 'manual', seg.manualLvl);
                    end
                end

                % --- NEW: convert UI area (µm²) -> pixels if calibrated ---
                if ~isempty(app.params.px_per_um) && isfinite(app.params.px_per_um) && app.params.px_per_um > 0
                    scaleA = app.params.px_per_um^2;  % (px/µm)^2
                    minApx = max(1, round(seg.minArea * scaleA));   % seg.minArea is in µm²
                    maxApx = round(seg.maxArea * scaleA);
                else
                    % No calibration yet: interpret slider as pixels (backward compatible)
                    minApx = max(1, round(seg.minArea));
                    maxApx = round(seg.maxArea);
                end

                % Size filter (now in pixels)
                if hasSizeFilter
                    BW = op_sizeFilter(BW, minApx, maxApx);
                else
                    BW = app.local_op_sizeFilter(BW, minApx, maxApx);
                end

                % Morphology (unchanged)
                if hasMorph
                    BW = op_morph(BW, seg.erode, seg.dilate);
                else
                    BW = app.local_op_morph(BW, seg.erode, seg.dilate);
                end

                % Watershed split of touching objects (optional)
                if seg.useWS && any(BW(:))
                    BW = app.local_op_watershed(BW, seg.hmin);
                    % Clean small fragments after splitting (use same pixel thresholds)
                    if hasSizeFilter
                        BW = op_sizeFilter(BW, minApx, maxApx);
                    else
                        BW = app.local_op_sizeFilter(BW, minApx, maxApx);
                    end
                end

                app.mask{c} = BW;
            end            
        end

        function updateViews(app)
            if isempty(app.img), return; end
            chs = app.params.channels.active;
            c1 = chs(1); c2 = chs(2);

            % Windowed images
            I1 = applyDisplayIntensity(app, app.img(:,:,c1), c1);
            I2 = applyDisplayIntensity(app, app.img(:,:,c2), c2);

            BW1 = app.mask{c1};
            BW2 = app.mask{c2};

            % --- counts per channel ---
            n1 = 0; if any(BW1(:)), n1 = bwconncomp(BW1).NumObjects; end
            n2 = 0; if any(BW2(:)), n2 = bwconncomp(BW2).NumObjects; end

            % Keep static channel titles + counts
            title(app.UIAxes,   sprintf('Selected Channel 1 — cells: %d', n1));
            title(app.UIAxes_2, sprintf('Selected Channel 2 — cells: %d', n2));

            % Ch1
            h = imagesc(app.UIAxes, I1); set(h,'HitTest','off','PickableParts','none');
            axis(app.UIAxes,'image'); app.UIAxes.XTick=[]; app.UIAxes.YTick=[];
            colormap(app.UIAxes, gray); hold(app.UIAxes,'on');
            visboundaries(app.UIAxes, BW1, 'Color','r'); hold(app.UIAxes,'off');

            % Ch2
            h = imagesc(app.UIAxes_2, I2); set(h,'HitTest','off','PickableParts','none');
            axis(app.UIAxes_2,'image'); app.UIAxes_2.XTick=[]; app.UIAxes_2.YTick=[];
            colormap(app.UIAxes_2, gray); hold(app.UIAxes_2,'on');
            visboundaries(app.UIAxes_2, BW2, 'Color','r'); hold(app.UIAxes_2,'off');

            % Overlay (R=Ch2, G=Ch1)
            RGB = zeros([size(I1) 3]);
            RGB(:,:,2) = mat2gray(I1); % green
            RGB(:,:,1) = mat2gray(I2); % red
            h = imagesc(app.UIAxes_3, RGB); set(h,'HitTest','off','PickableParts','none');
            axis(app.UIAxes_3,'image'); app.UIAxes_3.XTick=[]; app.UIAxes_3.YTick=[];
            hold(app.UIAxes_3,'on');
            Bint  = BW1 & BW2;

            % Boundary of pixel-wise overlap (cyan)
            % visboundaries(app.UIAxes_3, Bint, 'Color','k','LineWidth',3); % (optional halo)
            visboundaries(app.UIAxes_3, Bint, 'Color','b', 'LineWidth',1.5);

            % --- ROI overlap rule: Ch2 inside Ch1 ≥ slider threshold ---
            thrPct  = app.OverlapSlider.Value;         % 0..100
            thrFrac = thrPct/100;                      % 0..1
            [~, ~, nOverlapRule, isDoublePos] = computeOverlapCh2InCh1(app, BW1, BW2, thrFrac);

            % (Optional) Highlight Ch1 ROIs that pass the rule in magenta
            if any(isDoublePos)
                CC1 = bwconncomp(BW1);
                L1  = labelmatrix(CC1);
                BW_ch1_pass = ismember(L1, find(isDoublePos));
                visboundaries(app.UIAxes_3, BW_ch1_pass, 'Color', [1 0 1], 'LineWidth', 1.5);
            end

            hold(app.UIAxes_3,'off');

            % Pixel-level metrics
            inter    = nnz(Bint);
            unionPix = nnz(BW1 | BW2);
            dice     = (2*inter) / max(1, (nnz(BW1)+nnz(BW2)));
            iou      = inter / max(1, unionPix);

            % Title with both pixel and ROI metrics
            title(app.UIAxes_3, sprintf( ...
                'Overlay & Results — ROI overlap (Ch2→Ch1 ≥ %.0f%%): %d / %d  | Dice=%.3f | IoU=%.3f', ...
                thrPct, nOverlapRule, n1, dice, iou));
        end

        function x = normalizeTo01(~, slider)
            % Map slider value to [0..1] regardless of limits
            lo = slider.Limits(1); hi = slider.Limits(2);
            v  = slider.Value;
            x  = (v - lo) / max(1e-9, (hi - lo));
            x  = min(1, max(0, x));
        end

        function saveUIToChannel(app, c)
            % Intensity
            app.chan(c).intensity.auto = app.AutoCheckBox_ImageIntensity.Value;
            app.chan(c).intensity.hi   = min(1, max(0, app.ImageintensitySlider_2.Value));

            % Segmentation
            app.chan(c).seg.auto      = app.AutoCheckBox_MaskThreshold.Value;
            app.chan(c).seg.manualLvl = app.MaskThresholdSlider_2.Value;

            % --- µm² from the UI (do NOT round here) ---
            r = app.CellSizeSlider.Value;   % [min_um2, max_um2] in µm²
            app.chan(c).seg.minArea = max(1, r(1));
            app.chan(c).seg.maxArea = max(app.chan(c).seg.minArea, r(2));

            app.chan(c).seg.erode  = round(app.ErosionSlider_2.Value);
            app.chan(c).seg.dilate = round(app.DilationSlider_2.Value);

            app.chan(c).seg.useWS = logical(app.AutoCheckBox_Usewatershed.Value);
            app.chan(c).seg.hmin  = max(0, app.WatershedSlider.Value);
        end

        function loadChannelToUI(app, c)
            % Intensity
            app.AutoCheckBox_ImageIntensity.Value = app.chan(c).intensity.auto;
            app.ImageintensitySlider_2.Value      = max(0, min(1, app.chan(c).intensity.hi));

            % Segmentation
            app.AutoCheckBox_MaskThreshold.Value  = app.chan(c).seg.auto;
            app.MaskThresholdSlider_2.Value       = max(0, min(1, app.chan(c).seg.manualLvl));

            % --- µm² in the UI ---
            lim  = app.CellSizeSlider.Limits;     % still [1 1000] by default; units = µm²
            v    = [app.chan(c).seg.minArea, app.chan(c).seg.maxArea];
            v    = [max(lim(1), min(lim(2), v(1))), max(lim(1), min(lim(2), v(2)))];
            if v(2) < v(1), v(2) = v(1); end
            app.CellSizeSlider.Value = v;

            % Morphology
            app.ErosionSlider_2.Value  = app.chan(c).seg.erode;
            app.DilationSlider_2.Value = app.chan(c).seg.dilate;

            % Watershed controls
            app.AutoCheckBox_Usewatershed.Value = logical(app.chan(c).seg.useWS);
            app.WatershedSlider.Value = max(app.WatershedSlider.Limits(1), ...
                min(app.WatershedSlider.Limits(2), app.chan(c).seg.hmin));
        end

        function applyCalibrationFromUI(app, convertSliderFromPx)
            % Read µm/px from the edit field and update calibration + UI units.
            val = app.EditFieldm2pixelUnit.Value;   % µm per pixel (µm/px)
            if ~isempty(val) && isfinite(val) && val > 0
                app.params.px_per_um = 1/val;   % store as pixels per micron
                app.CellSizeSliderLabel.Text = 'Cell Area (µm^2)';

                % If this is the first time we set calibration (e.g., at startup),
                % and your slider previously represented *pixels*, convert it to µm² once.
                if nargin > 1 && convertSliderFromPx
                    scaleA = app.params.px_per_um^2;   % (px/µm)^2
                    r = app.CellSizeSlider.Value;      % assumed in px if convert flag is true
                    r_um2 = r / max(1e-12, scaleA);    % px -> µm²
                    lim = app.CellSizeSlider.Limits;
                    r_um2 = [max(lim(1), min(lim(2), r_um2(1))), ...
                        max(lim(1), min(lim(2), r_um2(2)))];
                    if r_um2(2) < r_um2(1), r_um2(2) = r_um2(1); end
                    app.CellSizeSlider.Value = r_um2;
                end
            else
                % No valid calibration => keep UI in pixels
                app.params.px_per_um = [];
                app.CellSizeSliderLabel.Text = 'Cell Area (px)';
            end
        end

        function setSelectedChannel(app, cNew)
            % Save current UI into the old selected channel
            if ~isempty(app.img) && ~isempty(app.chan)
                saveUIToChannel(app, app.selectedChan);
            end
            % Switch
            app.selectedChan = cNew;
            % Load the new channel settings into the UI
            loadChannelToUI(app, cNew);
            % Recompute & refresh using per-channel settings
            applySegmentation(app);
            updateViews(app);
        end

        function c = parseChValue(~, s)
            % 'Ch 1' -> 1
            c = sscanf(s,'Ch %d'); if isempty(c), c = 1; end
        end

        function [img, meta] = local_read_tif(~, fn)
            info = imfinfo(fn);
            if numel(info)==1
                A = imread(fn);
                if ndims(A)==2, A = reshape(A,size(A,1),size(A,2),1); end
                img = im2double(A);
            else
                C = numel(info);
                for i = 1:C
                    Ai = im2double(imread(fn,i));
                    if i==1, img = zeros(size(Ai,1), size(Ai,2), C); end
                    img(:,:,i) = Ai;
                end
            end
            meta = struct('filename', fn, 'size', size(img), 'bitdepth', info(1).BitDepth);
        end

        function BW = local_op_threshold(~, I, method, param)
            I = mat2gray(I);
            switch lower(method)
                case 'manual'
                    lvl = max(0, min(1, param));
                    BW  = I > lvl;
                case {'adaptive','local'}
                    if nargin<4 || isempty(param), param = 51; end
                    nbh = max(3, round(param)); if mod(nbh,2)==0, nbh = nbh+1; end
                    T = adaptthresh(I, 0.5, 'NeighborhoodSize', nbh);
                    BW = imbinarize(I, T);
                otherwise  % otsu
                    lvl = graythresh(I);
                    BW  = I > lvl;
            end
        end

        function Iadj = applyDisplayIntensity(app, I, c)
            % Apply contrast windowing for display using the per-channel state
            I = mat2gray(I);
            st = app.chan(c).intensity;
            if st.auto
                p = stretchlim(I,[0.01 0.99]);
                Iadj = imadjust(I, p, []);
            else
                hi = max(0.01, min(1, st.hi));
                Iadj = imadjust(I, [0 hi], []);
            end
        end

        function BW = local_op_sizeFilter(~, BW, minA, maxA)
            BW = bwareaopen(BW, max(1,round(minA)));
            if isfinite(maxA)
                CC = bwconncomp(BW); S = cellfun(@numel, CC.PixelIdxList);
                for k = find(S > maxA), BW(CC.PixelIdxList{k}) = false; end
            end
            BW = imclearborder(BW);
        end

        function BW = local_op_morph(~, BW, erode_r, dilate_r)
            if erode_r  > 0, BW = imerode(BW,  strel('disk', erode_r));  end
            if dilate_r > 0, BW = imdilate(BW, strel('disk', dilate_r)); end
        end
   
        function BWsplit = local_op_watershed(~, BW, h)
            % Split touching objects in BW using watershed on the distance transform.
            % h controls aggressiveness via h-minima; larger h => fewer splits.
            if nargin < 3 || isempty(h), h = 1; end
            % Distance from background; negative so that peaks (centers) become basins
            D = -bwdist(~BW);
            D(~BW) = -Inf;                 % keep background out of watershed
            if h > 0
                D = imhmin(D, h);          % suppress shallow minima (controls over-splitting)
            end
            L = watershed(D);
            BWsplit = BW;
            BWsplit(L==0) = false;         % watershed lines become object boundaries (set to 0)
        end
        
        function [nCh1, nCh2, nOverlap, isDoublePos] = computeOverlapCh2InCh1(app, BW1, BW2, thrFrac)
            % thrFrac in [0,1], e.g., 0.5 means ≥50% of a Ch2 ROI inside Ch1
            if nargin < 4 || isempty(thrFrac), thrFrac = 0.5; end

            % Connected components + labels
            CC1 = bwconncomp(BW1);        nCh1 = CC1.NumObjects;
            CC2 = bwconncomp(BW2);        nCh2 = CC2.NumObjects;
            if nCh1 == 0 || nCh2 == 0
                isDoublePos = false(nCh1,1);
                nOverlap    = 0;
                return;
            end

            L1  = labelmatrix(CC1);
            S2  = regionprops(CC2, 'PixelIdxList');

            % Valid Ch1 labels = 1:nCh1 (already size-filtered upstream)
            labels_ch1 = (1:nCh1).';
            ch1ValidMaskBinary = L1 > 0;

            % Map label number -> sequential index (here identity)
            label2idx = (1:nCh1).';

            isDoublePos = false(nCh1,1);

            for j = 1:nCh2
                pix = S2(j).PixelIdxList;
                if isempty(pix), continue; end

                fracInside = mean(ch1ValidMaskBinary(pix));    % fraction of Ch2 ROI inside Ch1
                if fracInside >= thrFrac
                    lbls = L1(pix);
                    lbls = lbls(lbls > 0);                     % keep only Ch1 labels
                    if ~isempty(lbls)
                        % winner = Ch1 object with most overlapping pixels
                        counts = accumarray(lbls, 1, [nCh1, 1]);
                        [~, winnerLabel] = max(counts);
                        if winnerLabel >= 1 && winnerLabel <= numel(label2idx)
                            idx = label2idx(winnerLabel);
                            if idx > 0, isDoublePos(idx) = true; end
                        end
                    end
                end
            end

            nOverlap = nnz(isDoublePos);  % number of Ch1 ROIs that pass the rule
        end

        function ax = getAxesForChannel(app, c)
            chs = app.params.channels.active;
            if ~isempty(chs)
                if c == chs(1), ax = app.UIAxes;   return; end
                if c == chs(2), ax = app.UIAxes_2; return; end
            end
            % Fallback
            ax = app.UIAxes;
        end
        
        function M = strokeMaskFromPolyline(~, pts, sz, r)
            % pts: Nx2 [x y], sz: [H W], r: radius in pixels
            H = sz(1); W = sz(2);
            Mthin = false(H, W);
            if size(pts,1) < 2, M = Mthin; return; end
            % draw each segment by dense interpolation
            for k = 1:size(pts,1)-1
                x1 = pts(k,1);   y1 = pts(k,2);
                x2 = pts(k+1,1); y2 = pts(k+1,2);
                n  = max(ceil(hypot(x2-x1, y2-y1)), 1);
                xs = round(linspace(x1, x2, n));
                ys = round(linspace(y1, y2, n));
                % keep inside image
                m = xs>=1 & xs<=W & ys>=1 & ys<=H;
                idx = sub2ind([H W], ys(m), xs(m));
                Mthin(idx) = true;
            end
            if r > 0
                M = imdilate(Mthin, strel('disk', round(r)));
            else
                M = Mthin;
            end
        end
        
        function doBrushEdit(app)
            if isempty(app.img), return; end
            c  = app.selectedChan;
            ax = app.getAxesForChannel(c);

            % Draw an open polyline
            h = drawpolyline(ax, 'Color',[0 0.8 0], 'LineWidth', 1.5);
            if ~isvalid(h), return; end
            wait(h);
            if ~isvalid(h) || size(h.Position,1) < 2   % or <1 for circle
                if isvalid(h), delete(h); end
                return;
            end
            pts = h.Position; delete(h);

            BW  = app.mask{c};
            r   = max(0, round(app.ToolsizeSlider.Value));   % tool size as radius
            stroke = app.strokeMaskFromPolyline(pts, size(BW), r);

            % Add stroke to mask to bridge gaps; then clean tiny holes
            BWnew = BW | stroke;
            % Optional: very mild closing to ensure bridge contacts
            BWnew = imclose(BWnew, strel('disk', max(1,ceil(r/2))));

            % Re-run connected component consolidation by just keeping as-is
            app.mask{c} = BWnew;

            updateViews(app);
        end

        function doEraserEdit(app)
            if isempty(app.img), return; end
            c  = app.selectedChan;
            ax = app.getAxesForChannel(c);

            % Freehand, OPEN trace (not closed)
            h = drawfreehand(ax, 'Color', [0.85 0 0], ...
                'FaceAlpha', 0.05, 'LineWidth', 1.5, 'Closed', false);
            if ~isvalid(h), return; end
            wait(h);
            if ~isvalid(h) || size(h.Position,1) < 2
                if isvalid(h), delete(h); end
                return;
            end
            pts = h.Position; delete(h);

            BW = app.mask{c};
            r  = max(0, round(app.ToolsizeSlider.Value));   % radius (px)
            stroke = app.strokeMaskFromPolyline(pts, size(BW), r);

            % Carve the stroke out of the mask to "cut" ROIs
            BWnew = BW & ~stroke;

            % Light cleanup so cuts separate cleanly
            BWnew = bwmorph(BWnew, 'spur', 2);

            app.mask{c} = BWnew;
            updateViews(app);
        end

        function doDeleteCircle(app)  % keep name if you like, now it's a lasso
            if isempty(app.img), return; end
            c  = app.selectedChan;
            ax = app.getAxesForChannel(c);

            % Freehand, CLOSED region (lasso)
            h = drawfreehand(ax, 'Color', [0.2 0.2 1], ...
                'FaceAlpha', 0.05, 'LineWidth', 1.5, 'Closed', true);
            if ~isvalid(h), return; end
            wait(h);
            if ~isvalid(h) || size(h.Position,1) < 3
                if isvalid(h), delete(h); end
                return;
            end
            maskSel = createMask(h); delete(h);

            BW = app.mask{c};
            if ~any(BW(:)), updateViews(app); return; end

            CC = bwconncomp(BW);
            L  = labelmatrix(CC);

            % Remove ROIs whose pixels are ALL inside the lasso
            toRemove = false(CC.NumObjects,1);
            for k = 1:CC.NumObjects
                pix = CC.PixelIdxList{k};
                if all(maskSel(pix))
                    toRemove(k) = true;
                end
            end
            if any(toRemove)
                BW(ismember(L, find(toRemove))) = false;
            end

            app.mask{c} = BW;
            updateViews(app);
        end

        function beginInteractive(app, tool)
            app.drawTool  = tool;          % arm tool
            app.isDrawing = false;         % first click will start
            app.drawPts   = zeros(0,2);

            if ~isempty(app.drawLine) && isvalid(app.drawLine), delete(app.drawLine); end
            app.drawLine = matlab.graphics.chart.primitive.Line.empty;

            try, app.UIFigure.Pointer = 'crosshair'; catch, end
            set([app.BrushButton app.EraserButton app.DeleteButton], 'Enable','off');
        end

        function setEditMode(app, mode)
            % mode: 'brush' | 'eraser' | 'delete' | 'none'
            app.editMode = mode;
            % Enforce mutual exclusivity visually/logic
            switch mode
                case 'brush'
                    app.BrushButton.Value  = true;
                    app.EraserButton.Value = false;
                    app.DeleteButton.Value = false;
                case 'eraser'
                    app.BrushButton.Value  = false;
                    app.EraserButton.Value = true;
                    app.DeleteButton.Value = false;
                case 'delete'
                    app.BrushButton.Value  = false;
                    app.EraserButton.Value = false;
                    app.DeleteButton.Value = true;
                otherwise
                    app.BrushButton.Value  = false;
                    app.EraserButton.Value = false;
                    app.DeleteButton.Value = false;
            end
        end

        function runOneEditInteraction(app)
            % Execute exactly one interaction for the current mode
            switch app.editMode
                case 'brush'
                    app.doBrushEdit();
                case 'eraser'
                    app.doEraserEdit();
                case 'delete'
                    app.doDeleteCircle();
                otherwise
                    % nothing
            end
        end

        function figMouseDown(app, event)
            if ~ismember(app.drawTool, {'brush','eraser','delete'}), return; end
            ax = app.getAxesForChannel(app.selectedChan);
            [x,y,inside] = app.getCurrentPointInAxes(ax);
            if ~inside, return; end

            if ~app.isDrawing
                % first click -> start trace
                app.isDrawing = true;
                app.drawPts = [x y];
                if ~isempty(app.drawLine) && isvalid(app.drawLine), delete(app.drawLine); end

                % Transparent green (80% transparent = 20% opaque -> EdgeAlpha = 0.2)
                app.drawLine = patch( ...
                    app.getAxesForChannel(app.selectedChan), ...
                    'Vertices', [x y], ...
                    'Faces',    [], ...                         % no segments yet
                    'FaceColor','none', ...
                    'EdgeColor',[0 1 0], ...
                    'EdgeAlpha',0.2, ...                        % <-- transparency
                    'LineWidth',1.5, ...
                    'HitTest','off','PickableParts','none');

                % Fallback if running on an older MATLAB without EdgeAlpha on uiaxes
                if ~isprop(app.drawLine,'EdgeAlpha')
                    % emulate "transparent look" by using a lighter green
                    app.drawLine.EdgeColor = [0.4 1 0.4];  % visually softer
                end
            else
                % second click -> finish + apply
                app.isDrawing = false;
                try app.UIFigure.Pointer = 'arrow'; catch, end
                switch app.drawTool
                    case 'brush',  app.applyBrushStroke();
                    case 'eraser', app.applyEraserStroke();
                    case 'delete', app.applyDeleteLasso();
                end
                if ~isempty(app.drawLine) && isvalid(app.drawLine), delete(app.drawLine); end
                app.drawLine = matlab.graphics.chart.primitive.Line.empty;
                app.drawPts  = zeros(0,2);
                app.drawTool = 'none';
                set([app.BrushButton app.EraserButton app.DeleteButton], 'Enable','on');
            end
        end

        function figMouseMotion(app, event)
            if ~app.isDrawing, return; end
            ax = app.getAxesForChannel(app.selectedChan);
            [x,y,inside] = app.getCurrentPointInAxes(ax);
            if ~inside, return; end

            if isempty(app.drawPts) || hypot(x-app.drawPts(end,1), y-app.drawPts(end,2)) >= 0.5
                app.drawPts(end+1,:) = [x y]; %#ok<AGROW>

                if ~isempty(app.drawLine) && isvalid(app.drawLine)
                    V = app.drawPts;
                    n = size(V,1);
                    if n >= 2
                        F = [(1:n-1)' (2:n)'];      % draw each segment i -> i+1 (open polyline)
                    else
                        F = [];
                    end
                    set(app.drawLine, 'Vertices', V, 'Faces', F);
                end
            end

        end

        function figKeyPress(app, event)
            if strcmp(event.Key,'escape') && app.isDrawing
                app.isDrawing = false;
                if ~isempty(app.drawLine) && isvalid(app.drawLine), delete(app.drawLine); end
                app.drawLine = matlab.graphics.chart.primitive.Line.empty;
                app.drawPts  = zeros(0,2);
                app.drawTool = 'none';
                try, app.UIFigure.Pointer = 'arrow'; catch, end
                set([app.BrushButton app.EraserButton app.DeleteButton], 'Enable','on');
            end
        end

        function [x,y,inside] = getCurrentPointInAxes(app, ax)
            cp = ax.CurrentPoint;
            x = cp(1,1); y = cp(1,2);
            xl = xlim(ax); yl = ylim(ax);
            inside = (x>=xl(1) && x<=xl(2) && y>=yl(1) && y<=yl(2));
        end

        function applyBrushStroke(app)
            c = app.selectedChan; BW = app.mask{c};
            if isempty(BW) || size(app.drawPts,1) < 2, return; end
            r = max(0, round(app.ToolsizeSlider.Value));
            stroke = app.strokeMaskFromPolyline(app.drawPts, size(BW), r);
            BW = imclose(BW | stroke, strel('disk', max(1,ceil(r/2))));
            app.mask{c} = BW; updateViews(app);
        end

        function applyEraserStroke(app)
            c = app.selectedChan; BW = app.mask{c};
            if isempty(BW) || size(app.drawPts,1) < 2, return; end
            r = max(0, round(app.ToolsizeSlider.Value));
            stroke = app.strokeMaskFromPolyline(app.drawPts, size(BW), r);
            BW = bwmorph(BW & ~stroke, 'spur', 2);
            app.mask{c} = BW; updateViews(app);
        end

        function applyDeleteLasso(app)
            c = app.selectedChan; BW = app.mask{c};
            if isempty(BW) || size(app.drawPts,1) < 3, return; end
            poly = app.drawPts; poly(end+1,:) = poly(1,:); % close
            sel = poly2mask(poly(:,1), poly(:,2), size(BW,1), size(BW,2));
            CC = bwconncomp(BW); if CC.NumObjects==0, updateViews(app); return; end
            L  = labelmatrix(CC);
            kill = false(CC.NumObjects,1);
            for k = 1:CC.NumObjects, if all(sel(CC.PixelIdxList{k})), kill(k)=true; end, end
            if any(kill), BW(ismember(L, find(kill))) = false; end
            app.mask{c} = BW; updateViews(app);
        end
        function ExportButtonPushed(app, event)
            % Guardrails
            if isempty(app.img) || isempty(app.meta) || ~isfield(app.meta,'filename') || ~exist(app.meta.filename,'file')
                uialert(app.UIFigure,'Please Import an image first.','Nothing to export');
                return;
            end

            % Resolve paths & filenames
            [p,name] = fileparts(app.meta.filename);
            if isempty(p), p = pwd; end
            pngOut  = fullfile(p, [name '_ROIs_post_process.png']);
            xlsxOut = fullfile(p, [name '_Summary_CellCounting.xlsx']);

            % Current channel choices
            chs = app.params.channels.active;
            c1 = chs(1);
            c2 = chs(2);

            % Current display images (match the app look)
            I1 = app.applyDisplayIntensity(app.img(:,:,c1), c1);
            I2 = app.applyDisplayIntensity(app.img(:,:,c2), c2);

            % Current masks & counts
            BW1 = app.mask{c1};
            BW2 = app.mask{c2};
            n1  = 0; if any(BW1(:)), n1 = bwconncomp(BW1).NumObjects; end
            n2  = 0; if any(BW2(:)), n2 = bwconncomp(BW2).NumObjects; end

            % Overlap rule (Ch2 inside Ch1 >= slider threshold)
            thrPct  = app.OverlapSlider.Value;
            thrFrac = thrPct / 100;
            [~, ~, nOverlapRule, isDoublePos] = app.computeOverlapCh2InCh1(BW1, BW2, thrFrac);

            % Pixel metrics for the overlay title
            Bint     = BW1 & BW2;
            inter    = nnz(Bint);
            unionPix = nnz(BW1 | BW2);
            dice     = (2*inter) / max(1, (nnz(BW1)+nnz(BW2)));
            iou      = inter / max(1, unionPix);

            % === Build a clean export figure (1x3) mirroring the app ===
            fig = figure('Visible','off','Color','w','Units','pixels','Position',[100 100 1800 600]); %#ok<UNRCH>
            try
                % Subplot 1: Ch1 with red boundaries
                ax1 = subplot(1,3,1,'Parent',fig);
                imshow(I1,'Parent',ax1); hold(ax1,'on');
                visboundaries(ax1, BW1, 'Color','r');
                title(ax1, sprintf('Selected Channel 1 — cells: %d', n1));
                axis(ax1,'image'); ax1.XTick=[]; ax1.YTick=[];

                % Subplot 2: Ch2 with red boundaries
                ax2 = subplot(1,3,2,'Parent',fig);
                imshow(I2,'Parent',ax2); hold(ax2,'on');
                visboundaries(ax2, BW2, 'Color','r');
                title(ax2, sprintf('Selected Channel 2 — cells: %d', n2));
                axis(ax2,'image'); ax2.XTick=[]; ax2.YTick=[];

                % Subplot 3: Overlay (R=Ch2, G=Ch1) + overlap boundaries
                ax3 = subplot(1,3,3,'Parent',fig);
                RGB = zeros([size(I1) 3]);
                RGB(:,:,2) = mat2gray(I1); % green
                RGB(:,:,1) = mat2gray(I2); % red
                imshow(RGB,'Parent',ax3); hold(ax3,'on');

                % Cyan boundary = pixelwise overlap
                visboundaries(ax3, Bint, 'Color','b', 'LineWidth', 1.5);

                % Magenta = Ch1 ROIs that pass the Ch2→Ch1 overlap rule
                if any(isDoublePos)
                    CC1 = bwconncomp(BW1);
                    L1  = labelmatrix(CC1);
                    BW_ch1_pass = ismember(L1, find(isDoublePos));
                    visboundaries(ax3, BW_ch1_pass, 'Color', [1 0 1], 'LineWidth', 1.5);
                end

                title(ax3, sprintf('Overlay & Results — ROI overlap (Ch2→Ch1 ≥ %.0f%%): %d / %d | Dice=%.3f | IoU=%.3f', ...
                    thrPct, nOverlapRule, n1, dice, iou));
                axis(ax3,'image'); ax3.XTick=[]; ax3.YTick=[];

                % A super-title with the source image name (optional)
                sgtitle(fig, sprintf('%s', name), 'FontWeight','bold');

                % Save PNG (300 dpi)
                exportgraphics(fig, pngOut, 'Resolution', 300);
            catch ME
                if isvalid(fig), close(fig); end
                rethrow(ME);
            end
            if isvalid(fig), close(fig); end

            % === Write Excel summary ===
            overlapPct = 100 * (nOverlapRule / max(1,n1));  % percentage (Ch2→Ch1)
            T = table( ...
                string(name), ...
                c1, c2, ...
                n1, n2, ...
                nOverlapRule, ...
                overlapPct, ...
                thrPct, ...
                dice, iou, ...
                'VariableNames', {'ImagePrefix','Ch1','Ch2','Ch1_CellCount','Ch2_CellCount', ...
                'ROI_Overlap_Count','ROI_Overlap_Percent','Overlap_Threshold_Pct','Dice','IoU'});

            writetable(T, xlsxOut, 'Sheet','Summary','WriteMode','overwritesheet');

            % Small toast
            uialert(app.UIFigure, ...
                sprintf('Saved:\n%s\n%s', pngOut, xlsxOut), ...
                'Export complete', 'Icon','success');
        end

        function cfg = buildConfigStruct(app)
            % Build a plain struct with everything we want to persist
            cfg = struct();
            cfg.version = 1;
            cfg.saved_at = char(datetime('now'));

            % Image info (optional / informative)
            if ~isempty(app.meta) && isfield(app.meta,'filename')
                cfg.image.filename = app.meta.filename;
            else
                cfg.image.filename = '';
            end

            % Channel selection shown in the two big panels
            c1 = app.parseChValue(app.ChannelstocompareDropDown.Value);
            c2 = app.parseChValue(app.ChannelstocompareDropDown_2.Value);
            cfg.channels.active = [c1 c2];

            % "Selected Channel" (the one whose sliders are currently controlling)
            cfg.channels.selected = app.parseChValue(app.SelectedChannelDropDown.Value);

            % Calibration
            cfg.calibration = struct( ...
                'um_per_px', app.EditFieldm2pixelUnit.Value);

            % Global overlap threshold (slider shown near overlay)
            cfg.overlapPct = app.OverlapSlider.Value;

            % Per-channel settings (intensity + segmentation)
            C = 0;
            if ~isempty(app.img)
                C = size(app.img,3);
            elseif ~isempty(app.chan)
                C = numel(app.chan);
            end
            if C == 0, C = 1; end

            cfg.per_channel = repmat(struct(), 1, C);
            for c = 1:C
                % If UI is currently pointed at this channel, make sure we capture
                % any unsaved UI changes into app.chan(c) first.
                if c == app.selectedChan
                    app.saveUIToChannel(c);
                end

                stI = app.chan(c).intensity;
                stS = app.chan(c).seg;

                cfg.per_channel(c).intensity = struct( ...
                    'auto', logical(stI.auto), ...
                    'hi',   double(stI.hi));

                cfg.per_channel(c).seg = struct( ...
                    'auto',      logical(stS.auto), ...
                    'manualLvl', double(stS.manualLvl), ...
                    'minArea',   double(stS.minArea), ...
                    'maxArea',   double(stS.maxArea), ...
                    'erode',     double(stS.erode), ...
                    'dilate',    double(stS.dilate), ...
                    'useWS',     logical(stS.useWS), ...
                    'hmin',      double(stS.hmin));
            end
        end

        function applyConfigStruct(app, cfg)
            % Guard: need an imported image (so we know how many channels exist)
            if isempty(app.img)
                uialert(app.UIFigure, ...
                    "Please Import an image first, then Load config so settings can be applied to it.", ...
                    'No image', 'Icon', 'warning');
                return;
            end

            Cimg = size(app.img,3);

            % Calibration (µm/px edit field drives conversion logic)
            if isfield(cfg,'calibration') && isfield(cfg.calibration,'um_per_px') ...
                    && ~isempty(cfg.calibration.um_per_px)
                app.EditFieldm2pixelUnit.Value = cfg.calibration.um_per_px;
                % Re-apply calibration labels/units but do not convert the current slider value
                app.m2pixelUnit([]);
            end

            % Overlap slider
            if isfield(cfg,'overlapPct') && ~isempty(cfg.overlapPct)
                v = max(app.OverlapSlider.Limits(1), ...
                    min(app.OverlapSlider.Limits(2), cfg.overlapPct));
                app.OverlapSlider.Value = v;
            end

            % Channel select (the two "compare" dropdowns)
            if isfield(cfg,'channels') && isfield(cfg.channels,'active') ...
                    && numel(cfg.channels.active) >= 2
                c1 = max(1, min(Cimg, cfg.channels.active(1)));
                c2 = max(1, min(Cimg, cfg.channels.active(2)));
                app.ChannelstocompareDropDown.Value   = sprintf('Ch %d', c1);
                app.ChannelstocompareDropDown_2.Value = sprintf('Ch %d', c2);
                app.params.channels.active = [c1 c2];
            end

            % Ensure app.chan matches image channel count
            if isempty(app.chan) || numel(app.chan) ~= Cimg
                % initialize (like onImport)
                app.chan = repmat(struct( ...
                    'intensity', struct('auto', false, 'hi', 0.1), ...
                    'seg', struct('auto', false, 'manualLvl', 0.2, ...
                    'adaptiveWin', 51, 'minArea', 30, 'maxArea', 2000, ...
                    'erode', 0, 'dilate', 0, 'useWS', false, 'hmin', 1.0)), ...
                    1, Cimg);
            end

            % Per-channel settings
            if isfield(cfg,'per_channel') && ~isempty(cfg.per_channel)
                N = min(Cimg, numel(cfg.per_channel));
                for c = 1:N
                    pc = cfg.per_channel(c);
                    if isfield(pc,'intensity')
                        ii = pc.intensity;
                        if isfield(ii,'auto'), app.chan(c).intensity.auto = logical(ii.auto); end
                        if isfield(ii,'hi'),   app.chan(c).intensity.hi   = max(0,min(1,double(ii.hi))); end
                    end
                    if isfield(pc,'seg')
                        sg = pc.seg;
                        f = @(name,default) (isfield(sg,name) && ~isempty(sg.(name))) * sg.(name) + ...
                            ~(isfield(sg,name) && ~isempty(sg.(name))) * default; %#ok<NASGU>
                        if isfield(sg,'auto'),      app.chan(c).seg.auto      = logical(sg.auto); end
                        if isfield(sg,'manualLvl'), app.chan(c).seg.manualLvl = double(sg.manualLvl); end
                        if isfield(sg,'minArea'),   app.chan(c).seg.minArea   = double(sg.minArea); end
                        if isfield(sg,'maxArea'),   app.chan(c).seg.maxArea   = max(double(sg.minArea), double(sg.maxArea)); end
                        if isfield(sg,'erode'),     app.chan(c).seg.erode     = round(double(sg.erode)); end
                        if isfield(sg,'dilate'),    app.chan(c).seg.dilate    = round(double(sg.dilate)); end
                        if isfield(sg,'useWS'),     app.chan(c).seg.useWS     = logical(sg.useWS); end
                        if isfield(sg,'hmin'),      app.chan(c).seg.hmin      = double(sg.hmin); end
                    end
                end
            end

            % Selected channel (which set of sliders is shown)
            if isfield(cfg,'channels') && isfield(cfg.channels,'selected') && ~isempty(cfg.channels.selected)
                sel = max(1, min(Cimg, cfg.channels.selected));
                app.selectedChan = sel;
                app.SelectedChannelDropDown.Value = sprintf('Ch %d', sel);
            end

            % Push selected channel’s stored values into the UI, recompute, refresh
            app.loadChannelToUI(app.selectedChan);
            app.applySegmentation();
            app.updateViews();
        end

        function SaveButtonPushed(app, ~)
            cfg = app.buildConfigStruct();

            % Choose a default folder (image folder if available)
            defaultFolder = pwd;
            defaultName = 'overlap_tool_config.json';
            if ~isempty(app.meta) && isfield(app.meta,'filename') && ~isempty(app.meta.filename)
                [p,name] = fileparts(app.meta.filename);
                if isfolder(p), defaultFolder = p; end
                defaultName = sprintf('%s_config.json', name);
            end

            [f,p] = uiputfile({'*.json','Config JSON (*.json)'}, 'Save configuration', ...
                fullfile(defaultFolder, defaultName));
            if isequal(f,0), return; end

            txt = jsonencode(cfg, 'PrettyPrint', true);
            fid = fopen(fullfile(p,f), 'w');
            if fid<0
                uialert(app.UIFigure,'Could not open file for writing.','Save failed','Icon','error');
                return;
            end
            fwrite(fid, txt, 'char'); fclose(fid);

            uialert(app.UIFigure, sprintf('Configuration saved:\n%s', fullfile(p,f)), ...
                'Saved', 'Icon', 'success');
        end

        function LoadButtonPushed(app, ~)
            [f,p] = uigetfile({'*.json','Config JSON (*.json)'}, 'Load configuration');
            if isequal(f,0), return; end

            fn = fullfile(p,f);
            try
                txt = fileread(fn);
                cfg = jsondecode(txt);
            catch ME
                uialert(app.UIFigure, sprintf('Could not read config:\n%s', ME.message), ...
                    'Load failed', 'Icon','error');
                return;
            end

            app.applyConfigStruct(cfg);
            % Optional toast with the source file
            uialert(app.UIFigure, sprintf('Configuration loaded:\n%s', fn), ...
                'Loaded', 'Icon','success');
        end

        function local_load_file(app, fnAbs, preserveState)
            if nargin < 3, preserveState = false; end

            % If preserving, capture any unsaved UI tweaks into app.chan first
            if preserveState && ~isempty(app.img) && ~isempty(app.chan)
                app.saveUIToChannel(app.selectedChan);
            end

            % --- Read the image ---
            if exist('io_readImage','file')
                [img, meta] = io_readImage(fnAbs);
            else
                [img, meta] = app.local_read_tif(fnAbs);
            end
            app.img  = img;
            app.meta = meta;

            % --- Channel UI items ---
            C     = size(img,3);
            items = arrayfun(@(i) sprintf('Ch %d', i), 1:C, 'uni', 0);
            app.ChannelstocompareDropDown.Items   = items;
            app.ChannelstocompareDropDown_2.Items = items;
            app.SelectedChannelDropDown.Items     = items;

            % Default per-channel struct (used only if we need to grow/init)
            defChan = struct( ...
                'intensity', struct('auto', false, 'hi', 0.1), ...
                'seg', struct('auto', false, 'manualLvl', 0.2, ...
                'adaptiveWin', 51, 'minArea', 30, 'maxArea', 2000, ...
                'erode', 0, 'dilate', 0, 'useWS', false, 'hmin', 1.0));

            if preserveState && ~isempty(app.chan)
                % --- Keep the *current* config/settings ---
                Cprev = numel(app.chan);
                if C > Cprev
                    app.chan(end+1:C) = repmat(defChan, 1, C-Cprev);
                elseif C < Cprev
                    app.chan = app.chan(1:C);
                end

                % Keep current compare channels if possible; clamp to [1..C]
                chs = app.params.channels.active;
                if isempty(chs) || numel(chs) < 2
                    chs = [1, min(2,C)];
                else
                    chs = [max(1,min(C,chs(1))), max(1,min(C,chs(2)))];
                end
                app.params.channels.active = chs;
                app.ChannelstocompareDropDown.Value   = sprintf('Ch %d', chs(1));
                app.ChannelstocompareDropDown_2.Value = sprintf('Ch %d', chs(2));

                % Keep selected channel if valid; otherwise fall back to 1
                if isempty(app.selectedChan) || app.selectedChan < 1 || app.selectedChan > C
                    app.selectedChan = 1;
                end
                app.SelectedChannelDropDown.Value = sprintf('Ch %d', app.selectedChan);
            else
                % --- Fresh init (single import or first run) ---
                app.chan = repmat(defChan, 1, C);
                app.ChannelstocompareDropDown.Value   = items{1};
                app.ChannelstocompareDropDown_2.Value = items{min(2,C)};
                app.SelectedChannelDropDown.Value     = items{1};
                app.selectedChan = 1;
            end

            % Reset masks for the new image; segmentation will fill them
            app.mask = cell(1,C);
            for c = 1:C, app.mask{c} = false(size(img,1), size(img,2)); end

            % Push currently selected channel’s saved settings into the UI
            loadChannelToUI(app, app.selectedChan);

            % Recompute & refresh using the (preserved) settings
            applySegmentation(app);
            updateViews(app);
        end

        
        function T = currentMetricsTable(app)
            % Build one-row table with columns requested for the batch summary
            if isempty(app.img) || isempty(app.meta) || ~isfield(app.meta,'filename')
                T = table(); return;
            end
            [~, name] = fileparts(app.meta.filename);

            chs = app.params.channels.active;
            c1 = chs(1); c2 = chs(2);
            BW1 = app.mask{c1}; BW2 = app.mask{c2};
            n1  = 0; if any(BW1(:)), n1 = bwconncomp(BW1).NumObjects; end
            n2  = 0; if any(BW2(:)), n2 = bwconncomp(BW2).NumObjects; end

            thrPct  = app.OverlapSlider.Value;
            thrFrac = thrPct/100;
            [~, ~, nOverlapRule, ~] = app.computeOverlapCh2InCh1(BW1, BW2, thrFrac);

            Bint     = BW1 & BW2;
            inter    = nnz(Bint);
            unionPix = nnz(BW1 | BW2);
            dice     = (2*inter) / max(1,(nnz(BW1)+nnz(BW2)));
            iou      = inter / max(1,unionPix);

            overlapPct = 100 * (nOverlapRule / max(1,n1));

            T = table( ...
                string(name), c1, c2, n1, n2, nOverlapRule, overlapPct, thrPct, dice, iou, ...
                'VariableNames', {'ImagePrefix','Ch1','Ch2','Ch1_CellCount','Ch2_CellCount', ...
                'ROI_Overlap_Count','ROI_Overlap_Percent','Overlap_Threshold_Pct','Dice','IoU'});
        end


        function batchStart(app, filesAbs)
            app.batch.files   = filesAbs(:)';
            app.batch.idx     = 1;
            app.batch.root    = fileparts(filesAbs{1});
            app.batch.summary = table();        % empty
            app.batch.running = true;

            % Load first file
            app.local_load_file(app.batch.files{app.batch.idx}, true);
            uialert(app.UIFigure, sprintf('Batch started (%d files).', numel(app.batch.files)), ...
                'Batch', 'Icon','info');
        end

        function tf = batchHasNext(app)
            tf = app.batch.running && app.batch.idx < numel(app.batch.files);
        end

        function batchGotoNext(app)
            if ~app.batch.running, return; end

            % Persist any slider/checkbox changes into app.chan
            if ~isempty(app.img) && ~isempty(app.chan)
                app.saveUIToChannel(app.selectedChan);
            end

            if app.batch.idx < numel(app.batch.files)
                app.batch.idx = app.batch.idx + 1;
                app.local_load_file(app.batch.files{app.batch.idx}, true);  % preserve = true
            else
                app.batch.running = false;
                uialert(app.UIFigure, 'Batch finished. You can "Save all" to write the summary.', ...
                    'Done', 'Icon','success');
            end
        end
        
        function resetBatch(app)
            app.batch.files   = {};
            app.batch.idx     = 0;
            app.batch.root    = '';
            app.batch.summary = table();
            app.batch.running = false;
        end

        function y = ternary(~, cond, a, b)
            if cond, y = a; else, y = b; end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p = 'C:\Users\pingdong\Desktop\2025-045 Overlap Tool\MATLAB_GUI_V1';
            if isfolder(p), addpath(genpath(p)); end
            initParams(app);
            applyCalibrationFromUI(app, true);
            % === Hook figure-level mouse handlers (single-click start/stop) ===
            app.UIFigure.WindowButtonDownFcn   = @(src,evt) app.figMouseDown(evt);
            app.UIFigure.WindowButtonMotionFcn = @(src,evt) app.figMouseMotion(evt);
            app.UIFigure.KeyPressFcn           = @(src,evt) app.figKeyPress(evt);

            % === Wire the three push buttons ===
            app.BrushButton.ButtonPushedFcn  = @(src,evt) app.BrushButtonPushed(evt);
            app.EraserButton.ButtonPushedFcn = @(src,evt) app.EraserButtonPushed(evt);
            app.DeleteButton.ButtonPushedFcn = @(src,evt) app.DeleteButtonPushed(evt);
            app.ExportButton.ButtonPushedFcn = @(src,evt) app.ExportButtonPushed(evt); 
            
            % === Wire config buttons ===
            app.SaveButton.ButtonPushedFcn = @(src,evt) app.SaveButtonPushed(evt);
            app.LoadButton.ButtonPushedFcn = @(src,evt) app.LoadButtonPushed(evt);

        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            onImport(app);
        end

        % Value changed function: ChannelstocompareDropDown
        function ChannelstocompareDropDownValueChanged(app, event)
            value = app.ChannelstocompareDropDown.Value;
            applySegmentation(app); updateViews(app);
        end

        % Value changed function: ChannelstocompareDropDown_2
        function ChannelstocompareDropDown_2ValueChanged(app, event)
            value = app.ChannelstocompareDropDown_2.Value;
            applySegmentation(app); updateViews(app);
        end

        % Value changed function: MaskThresholdSlider_2
        function MaskThresholdSlider_2ValueChanged(app, event)
            saveUIToChannel(app, app.selectedChan);
            applySegmentation(app); updateViews(app);
        end

        % Value changed function: AutoCheckBox_MaskThreshold
        function AutoCheckBox_MaskThresholdValueChanged(app, event)
            saveUIToChannel(app, app.selectedChan);
            applySegmentation(app); updateViews(app);
        end

        % Value changed function: CellSizeSlider
        function CellSizeSliderValueChangedMin(app, event)
            saveUIToChannel(app, app.selectedChan);
            applySegmentation(app);updateViews(app);
        end

        % Value changing function: CellSizeSlider
        function CellSizeSliderValueChangedMax(app, event)
            saveUIToChannel(app, app.selectedChan);
            applySegmentation(app);
            updateViews(app);
        end

        % Value changed function: DilationSlider_2
        function DilationSlider_2ValueChanged(app, event)
            saveUIToChannel(app, app.selectedChan);
            applySegmentation(app);
            updateViews(app);
        end

        % Value changed function: ErosionSlider_2
        function ErosionSlider_2ValueChanged(app, event)
            saveUIToChannel(app, app.selectedChan);
            applySegmentation(app);
            updateViews(app);
        end

        % Value changed function: ImageintensitySlider_2
        function ImageintensitySliderValueChanged(app, event)
            saveUIToChannel(app, app.selectedChan);
            updateViews(app);
        end

        % Value changed function: AutoCheckBox_ImageIntensity
        function AutoCheckBox_ImageIntensityValueChanged(app, event)
            saveUIToChannel(app, app.selectedChan);
            updateViews(app);
        end

        % Value changed function: EditFieldm2pixelUnit
        function m2pixelUnit(app, event)
            applyCalibrationFromUI(app, false);   % just (re)apply calibration + labels
            % Recompute if an image is already loaded
            if ~isempty(app.img)
                saveUIToChannel(app, app.selectedChan);
                applySegmentation(app);
                updateViews(app);
            end
        end

        % Value changed function: SelectedChannelDropDown
        function SelectedChannelDropDownValueChanged(app, event)
            % Save the current channel’s UI settings
            saveUIToChannel(app, app.selectedChan);

            % Parse 'Ch N' -> N and switch
            c = sscanf(app.SelectedChannelDropDown.Value,'Ch %d');
            if isempty(c), c = 1; end
            app.selectedChan = c;

            % Load the new channel’s saved settings into the controls
            loadChannelToUI(app, c);

            % Recompute & refresh
            applySegmentation(app);
            updateViews(app);
        end

        % Value changed function: AutoCheckBox_Usewatershed
        function UseWatershedCheckBoxValueChanged(app, event)
            saveUIToChannel(app, app.selectedChan);
            applySegmentation(app);
            updateViews(app);
        end

        % Value changed function: WatershedSlider
        function WatershedHSliderValueChanged(app, event)
            saveUIToChannel(app, app.selectedChan);
            applySegmentation(app);
            updateViews(app);
        end

        % Value changed function: OverlapSlider
        function OverlapSliderValueChanged(app, event)
            updateViews(app);
        end

        % Button pushed function: BrushButton
        function BrushButtonPushed(app, event)
            app.beginInteractive('brush'); 
        end

        % Button pushed function: EraserButton
        function EraserButtonPushed(app, event)
            app.beginInteractive('eraser');
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
           app.beginInteractive('delete'); 
        end

        % Button pushed function: BatchImportButton
        function BatchImportButtonPushed(app, event)
            [f,p] = uigetfile({'*.tif;*.tiff','TIFF images'}, ...
                'Select multiple TIFF images', 'MultiSelect','on');
            if isequal(f,0), return; end
            if ischar(f), f = {f}; end
            filesAbs = fullfile(p, f);

            % Randomize order if requested (user won’t see names; order is shuffled)
            if app.RandomizeCheckBox.Value
                filesAbs = filesAbs(randperm(numel(filesAbs)));
            end

            app.batchStart(filesAbs);
        end

        % Button pushed function: SaveNextButton
        function SaveNextButtonPushed(app, event)
            if ~app.batch.running
                % allow single-file use as a convenience
                app.ExportButtonPushed([]);
                return;
            end

            % Reuse the single-image exporter for identical outputs
            app.ExportButtonPushed([]);

            % Append one summary row in memory
            T = app.currentMetricsTable();
            if ~isempty(T)
                if isempty(app.batch.summary)
                    app.batch.summary = T;
                else
                    app.batch.summary = [app.batch.summary; T]; %#ok<AGROW>
                end
            end

            % Advance
            app.batchGotoNext();
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            if ~app.batch.running
                uialert(app.UIFigure,'No active batch. Use Batch Import first.','Skip');
                return;
            end
            app.batchGotoNext();
        end

        % Button pushed function: SaveallButton
        function SaveallButtonPushed(app, event)
            if isempty(app.batch.summary) || height(app.batch.summary)==0
                uialert(app.UIFigure,'No rows collected yet. Use "Save & Next" during the batch first.', ...
                    'Nothing to save','Icon','warning');
                return;
            end

            outFn = fullfile( ...
                app.ternary(~isempty(app.batch.root), app.batch.root, pwd), ...
                'Summary_of_all_tif_overlaping.xlsx');

            try
                writetable(app.batch.summary, outFn, 'Sheet','Summary','WriteMode','overwritesheet');
                uialert(app.UIFigure, sprintf('Saved summary:\n%s', outFn), 'Saved','Icon','success');
            catch ME
                uialert(app.UIFigure, sprintf('Failed to save summary:\n%s', ME.message), ...
                    'Error','Icon','error');
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            if ~app.batch.running
                uialert(app.UIFigure,'No active batch.','Quit'); return;
            end
            app.batch.running = false;
            uialert(app.UIFigure,'Batch session stopped. You can still "Save all" current summary.', ...
                'Batch stopped','Icon','warning');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Selected Channel 1')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Selected Channel 2')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Overlay & Results')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.FileandConfigSettingPanel = uipanel(app.UIFigure);
            app.FileandConfigSettingPanel.Title = 'File and Config Setting';
            app.ImportButton = uibutton(app.FileandConfigSettingPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.FileandConfigSettingPanel, 'push');
            app.ExportButton.Text = 'Export';
            app.SaveButton = uibutton(app.FileandConfigSettingPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.SingleFileLabel = uilabel(app.FileandConfigSettingPanel);
            app.SingleFileLabel.Text = 'Single File';
            app.ConfigLabel = uilabel(app.FileandConfigSettingPanel);
            app.ConfigLabel.Text = 'Config';
            app.LoadButton = uibutton(app.FileandConfigSettingPanel, 'push');
            app.LoadButton.Text = 'Load';
            app.RatioLabel = uilabel(app.FileandConfigSettingPanel);
            app.RatioLabel.Text = 'Ratio';
            app.mpixelEditFieldLabel = uilabel(app.FileandConfigSettingPanel);
            app.mpixelEditFieldLabel.Text = 'μm/pixel';
            app.EditFieldm2pixelUnit = uieditfield(app.FileandConfigSettingPanel, 'numeric');
            app.EditFieldm2pixelUnit.ValueChangedFcn = createCallbackFcn(app, @m2pixelUnit, true);
            app.EditFieldm2pixelUnit.Value = 0.65;
            app.SelectedChanneltoComparePanel = uipanel(app.UIFigure);
            app.SelectedChanneltoComparePanel.Title = 'Selected Channel to Compare';
            app.ChannelstocompareDropDown = uidropdown(app.SelectedChanneltoComparePanel);
            app.ChannelstocompareDropDown.Items = {'Ch 1', 'Ch 2', 'Ch 3', 'Ch 4'};
            app.ChannelstocompareDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelstocompareDropDownValueChanged, true);
            app.ChannelstocompareDropDown.Value = 'Ch 1';
            app.ChannelstocompareDropDown_2 = uidropdown(app.SelectedChanneltoComparePanel);
            app.ChannelstocompareDropDown_2.Items = {'Ch 1', 'Ch 2', 'Ch 3', 'Ch 4'};
            app.ChannelstocompareDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelstocompareDropDown_2ValueChanged, true);
            app.ChannelstocompareDropDown_2.Value = 'Ch 2';
            app.ImageprocessingPanel = uipanel(app.UIFigure);
            app.ImageprocessingPanel.Title = 'Image processing';
            app.AutoCheckBox_ImageIntensity = uicheckbox(app.ImageprocessingPanel);
            app.AutoCheckBox_ImageIntensity.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBox_ImageIntensityValueChanged, true);
            app.AutoCheckBox_ImageIntensity.Text = 'Auto';
            app.ImageintensitySlider_2Label = uilabel(app.ImageprocessingPanel);
            app.ImageintensitySlider_2Label.Text = 'Image intensity';
            app.ImageintensitySlider_2 = uislider(app.ImageprocessingPanel);
            app.ImageintensitySlider_2.Limits = [0 1];
            app.ImageintensitySlider_2.ValueChangedFcn = createCallbackFcn(app, @ImageintensitySliderValueChanged, true);
            app.ImageintensitySlider_2.Value = 0.1;
            app.MaskThresholdSlider_2Label = uilabel(app.ImageprocessingPanel);
            app.MaskThresholdSlider_2Label.Text = 'Mask Threshold';
            app.MaskThresholdSlider_2 = uislider(app.ImageprocessingPanel);
            app.MaskThresholdSlider_2.Limits = [0 1];
            app.MaskThresholdSlider_2.ValueChangedFcn = createCallbackFcn(app, @MaskThresholdSlider_2ValueChanged, true);
            app.MaskThresholdSlider_2.Value = 0.1;
            app.AutoCheckBox_MaskThreshold = uicheckbox(app.ImageprocessingPanel);
            app.AutoCheckBox_MaskThreshold.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBox_MaskThresholdValueChanged, true);
            app.AutoCheckBox_MaskThreshold.Text = 'Auto';
            app.CellSizeSliderLabel = uilabel(app.ImageprocessingPanel);
            app.CellSizeSliderLabel.Text = 'Cell Size';
            app.CellSizeSlider = uislider(app.ImageprocessingPanel, 'range');
            app.CellSizeSlider.Limits = [1 1500];
            app.CellSizeSlider.MajorTicks = [1 200 400 600 800 1000 1200 1500];
            app.CellSizeSlider.ValueChangedFcn = createCallbackFcn(app, @CellSizeSliderValueChangedMin, true);
            app.CellSizeSlider.ValueChangingFcn = createCallbackFcn(app, @CellSizeSliderValueChangedMax, true);
            app.CellSizeSlider.Step = 1;
            app.CellSizeSlider.Value = [50 800];
            app.DilationSlider_2Label = uilabel(app.ImageprocessingPanel);
            app.DilationSlider_2Label.Text = 'Dilation';
            app.DilationSlider_2 = uislider(app.ImageprocessingPanel);
            app.DilationSlider_2.Limits = [0 10];
            app.DilationSlider_2.ValueChangedFcn = createCallbackFcn(app, @DilationSlider_2ValueChanged, true);
            app.ErosionSlider_2Label = uilabel(app.ImageprocessingPanel);
            app.ErosionSlider_2Label.Text = 'Erosion';
            app.ErosionSlider_2 = uislider(app.ImageprocessingPanel);
            app.ErosionSlider_2.Limits = [0 10];
            app.ErosionSlider_2.ValueChangedFcn = createCallbackFcn(app, @ErosionSlider_2ValueChanged, true);
            app.SelectedChannelDropDownLabel = uilabel(app.ImageprocessingPanel);
            app.SelectedChannelDropDownLabel.Text = 'Selected Channel';
            app.SelectedChannelDropDown = uidropdown(app.ImageprocessingPanel);
            app.SelectedChannelDropDown.Items = {'Ch 1', 'Ch 2', 'Ch 3', 'Ch 4'};
            app.SelectedChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedChannelDropDownValueChanged, true);
            app.SelectedChannelDropDown.Value = 'Ch 1';
            app.AutoCheckBox_Usewatershed = uicheckbox(app.ImageprocessingPanel);
            app.AutoCheckBox_Usewatershed.ValueChangedFcn = createCallbackFcn(app, @UseWatershedCheckBoxValueChanged, true);
            app.AutoCheckBox_Usewatershed.Text = 'Use watershed';
            app.AutoCheckBox_Usewatershed.Value = true;
            app.WatershedLabel = uilabel(app.ImageprocessingPanel);
            app.WatershedLabel.Text = 'Watershed';
            app.WatershedSlider = uislider(app.ImageprocessingPanel);
            app.WatershedSlider.Limits = [0 10];
            app.WatershedSlider.ValueChangedFcn = createCallbackFcn(app, @WatershedHSliderValueChanged, true);
            app.WatershedSlider.Value = 1;
            app.OverlapSliderLabel = uilabel(app.ImageprocessingPanel);
            app.OverlapSliderLabel.Text = 'Overlap%';
            app.OverlapSlider = uislider(app.ImageprocessingPanel);
            app.OverlapSlider.ValueChangedFcn = createCallbackFcn(app, @OverlapSliderValueChanged, true);
            app.OverlapSlider.Value = 80;
            app.ManualROIToolsPanel = uipanel(app.ImageprocessingPanel);
            app.ManualROIToolsPanel.Title = 'Manual ROI Tools';
            app.BrushButton = uibutton(app.ManualROIToolsPanel, 'push');
            app.BrushButton.ButtonPushedFcn = createCallbackFcn(app, @BrushButtonPushed, true);
            app.BrushButton.Text = 'Brush';
            app.EraserButton = uibutton(app.ManualROIToolsPanel, 'push');
            app.EraserButton.ButtonPushedFcn = createCallbackFcn(app, @EraserButtonPushed, true);
            app.EraserButton.Text = 'Eraser';
            app.DeleteButton = uibutton(app.ManualROIToolsPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.ToolsizeSliderLabel = uilabel(app.ManualROIToolsPanel);
            app.ToolsizeSliderLabel.Text = 'Tool size';
            app.ToolsizeSlider = uislider(app.ManualROIToolsPanel);
            app.ToolsizeSlider.Value = 1;
            app.BatchProcessPanel = uipanel(app.UIFigure);
            app.BatchProcessPanel.Title = 'Batch Process';
            app.BatchImportButton = uibutton(app.BatchProcessPanel, 'push');
            app.BatchImportButton.ButtonPushedFcn = createCallbackFcn(app, @BatchImportButtonPushed, true);
            app.BatchImportButton.Text = 'Batch Import';
            app.SaveNextButton = uibutton(app.BatchProcessPanel, 'push');
            app.SaveNextButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNextButtonPushed, true);
            app.SaveNextButton.Text = 'Save & Next';
            app.QuitButton = uibutton(app.BatchProcessPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.RandomizeCheckBox = uicheckbox(app.BatchProcessPanel);
            app.RandomizeCheckBox.Text = 'Randomize';
            app.SkipButton = uibutton(app.BatchProcessPanel, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.SaveallButton = uibutton(app.BatchProcessPanel, 'push');
            app.SaveallButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallButtonPushed, true);
            app.SaveallButton.Text = 'Save all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cell_overlap_V1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end