classdef Image_Segment_Annotation_V1_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SegmentandAnnotationPanel     matlab.ui.container.Panel
        UndoButton                    matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        EditButton                    matlab.ui.control.Button
        PolygonButton                 matlab.ui.control.Button
        ROItoolsLabel                 matlab.ui.control.Label
        FreehandButton                matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        RegionNameEditField           matlab.ui.control.EditField
        RegionNameEditFieldLabel      matlab.ui.control.Label
        ImageprocessPanel             matlab.ui.container.Panel
        ColorLUTDropDown              matlab.ui.control.DropDown
        ColorLUTDropDownLabel         matlab.ui.control.Label
        ChannelShowPanel              matlab.ui.container.Panel
        Ch4CheckBox                   matlab.ui.control.CheckBox
        Ch3CheckBox                   matlab.ui.control.CheckBox
        Ch2CheckBox                   matlab.ui.control.CheckBox
        Ch1CheckBox                   matlab.ui.control.CheckBox
        ImageintensitySlider          matlab.ui.control.Slider
        ImageintensitySliderLabel     matlab.ui.control.Label
        EditFieldm2pixelUnit          matlab.ui.control.NumericEditField
        mpixelEditFieldLabel          matlab.ui.control.Label
        RatioLabel                    matlab.ui.control.Label
        SelectedChannelDropDown       matlab.ui.control.DropDown
        SelectedChannelDropDownLabel  matlab.ui.control.Label
        FilePanel                     matlab.ui.container.Panel
        ExportButton                  matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % image & display
        ImgRaw            % cell{C} of 2D images (double in [0,1])
        Nch               % number of channels
        ImgSize           % [H W]
        FilePath          % char
        ScaleUmPerPix = 0.65

        % per-channel display params
        Disp             % struct array with fields: min,max,lut,visible
        % e.g., Disp(c).min/max in [0,1], .lut = 'Blue', .visible = true/false

        % ROI management
        RoiList          % struct array with fields: id,name,vertices,mask,area_um2,visible=true
        NextRoiID = 1
        ActiveRoi        % images.roi handle being drawn/edited
        SourceBitsPerSample = 8;   % 8 or 16 typically; used to preserve original bit depth on export
        ActiveDrawAxes  % uiaxes handle: UIAxes or UIAxes_2

        % Full-resolution metadata (no need to keep full frames in RAM)
        FullInfo            % imfinfo(app.FilePath)
        FullSize            % [Hfull Wfull]
        FullNch             % number of channels detected at full-res

        % Preview (decimated) data for fast display
        PreviewStep = 2     % 2–4 is a sweet spot; make it a UI control if you like
        ImgPreview          % cell{FullNch} of preview frames (uint16)
        PreviewSize         % [Hprev Wprev]

        % Graphics handles for faster refresh
        hImgSel
        hImgMerge

    end

    methods (Access = private)


        function onImport(app)
            [f,p] = uigetfile({'*.tif;*.tiff','TIFF files'});
            if isequal(f,0), return; end
            app.FilePath = fullfile(p,f);

            app.FullInfo = imfinfo(app.FilePath);
            app.SourceBitsPerSample = isfield(app.FullInfo(1),'BitsPerSample') ...
                .* app.FullInfo(1).BitsPerSample(1) + ~isfield(app.FullInfo(1),'BitsPerSample') ...
                .* min(16, max(8, app.FullInfo(1).BitDepth));

            % Detect channel layout
            isMultiSample = isfield(app.FullInfo(1),'SamplesPerPixel') && app.FullInfo(1).SamplesPerPixel>1;

            if isMultiSample
                app.FullNch = app.FullInfo(1).SamplesPerPixel;
                app.FullSize = [app.FullInfo(1).Height, app.FullInfo(1).Width];
            else
                app.FullNch = numel(app.FullInfo);
                app.FullSize = [app.FullInfo(1).Height, app.FullInfo(1).Width];
            end

            % --- Build preview (decimated read) ---
            step = max(1, round(app.PreviewStep));       % safety
            rows = [1 step app.FullSize(1)];             % [START INCR STOP]
            cols = [1 step app.FullSize(2)];             % [START INCR STOP]
            rc   = {rows, cols};

            app.ImgPreview = cell(1, app.FullNch);

            if isMultiSample
                % One page with multiple samples/channels
                Iprev = imread(app.FilePath, 'Index', 1, 'PixelRegion', rc);
                for c = 1:app.FullNch
                    app.ImgPreview{c} = Iprev(:,:,c);    % keep uint16/uint8
                end
            else
                % Multi-page: one channel per page
                for c = 1:app.FullNch
                    app.ImgPreview{c} = imread(app.FilePath, 'Index', c, ...
                        'Info', app.FullInfo, 'PixelRegion', rc);
                end
            end


            app.PreviewSize = size(app.ImgPreview{1}, [1 2]);
            app.Nch = app.FullNch;     % keep UI logic unchanged
            app.ImgSize = app.PreviewSize;

            % Init display params (fast percentiles on a 1% sample)
            defaultLUT = {'Blue','Green','Red','Magenta'};
            app.Disp = struct('min',{},'max',{},'lut',{},'visible',{});
            for c=1:app.Nch
                [lo,hi] = app.fastPercentiles(app.ImgPreview{c}, 1, 99, 0.01);
                rng = double(intmax(class(app.ImgPreview{c})));
                app.Disp(c).min = lo / rng;
                app.Disp(c).max = hi / rng;
                app.Disp(c).lut = defaultLUT{1+mod(c-1,numel(defaultLUT))};
                app.Disp(c).visible = (c<=3);
            end

            % Sync UI
            set([app.Ch1CheckBox,app.Ch2CheckBox,app.Ch3CheckBox,app.Ch4CheckBox], 'Enable','off','Value',false);
            for c = 1:min(app.Nch,4)
                cb = app.(['Ch' num2str(c) 'CheckBox']); cb.Enable = 'on'; cb.Value = app.Disp(c).visible;
            end
            app.SelectedChannelDropDown.Items = strcat("Ch ",string(1:app.Nch));
            app.SelectedChannelDropDown.Value = "Ch 1";
            app.ColorLUTDropDown.Value = app.Disp(1).lut;
            app.ImageintensitySlider.Value = app.Disp(1).max;
            app.ScaleUmPerPix = app.EditFieldm2pixelUnit.Value;

            % Reset ROIs & table
            app.RoiList = struct('id',{},'name',{},'vertices',{},'area_um2',{},'visible',{});
            app.NextRoiID = 1;
            app.UITable.Data = cell(0,4);

            showSelectedChannel(app);
            showMerged(app);
        end

        function [lo,hi] = fastPercentiles(app, I, pLo, pHi, samprate)
            if nargin<5, samprate=0.01; end
            n = numel(I);
            idx = randperm(n, max(1, round(n*samprate)));
            s = double(I(idx));
            lo = prctile(s, pLo);
            hi = prctile(s, pHi);
        end


        function onSelectedChannelChanged(app)
            c = getSelectedChannelIndex(app);
            app.ColorLUTDropDown.Value = app.Disp(c).lut;
            app.ImageintensitySlider.Value = app.Disp(c).max;
            showSelectedChannel(app);
        end

        function onColorLUTChanged(app)
            c = getSelectedChannelIndex(app);
            app.Disp(c).lut = app.ColorLUTDropDown.Value;
            showSelectedChannel(app); showMerged(app);
        end

        function onIntensityChanged(app)
            % simple: map slider to max; keep min fixed for now
            c = getSelectedChannelIndex(app);
            app.Disp(c).max = max(app.Disp(c).min + 1e-6, app.ImageintensitySlider.Value);
            showSelectedChannel(app); showMerged(app);
        end

        function onScaleChanged(app)
            app.ScaleUmPerPix = app.EditFieldm2pixelUnit.Value;
            % areas recompute only when ROIs added/edited; keep as-is
        end

        function onChannelShowChanged(app,c)
            if c>app.Nch, return; end
            cb = app.(['Ch' num2str(c) 'CheckBox']);
            app.Disp(c).visible = logical(cb.Value);
            showMerged(app);
        end

        function showSelectedChannel(app)
    if isempty(app.ImgPreview), return; end
    c  = getSelectedChannelIndex(app);
    I  = app.ImgPreview{c};

    if isempty(app.hImgSel) || ~isvalid(app.hImgSel)
        app.hImgSel = image(app.UIAxes, I, 'CDataMapping','scaled');
        axis(app.UIAxes,'image'); app.UIAxes.Visible='off';
    else
        app.hImgSel.CData = I;
    end

    rngv = double(intmax(class(I)));
    set(app.UIAxes,'CLim',[app.Disp(c).min app.Disp(c).max]*rngv);

    % (Re)draw ROI overlays only (don’t reset the axes)
    delete(findall(app.UIAxes,'Tag','roiOverlay'));  % <-- clear old overlays
    drawAllRoiOutlines(app, app.UIAxes);
end
        
        function showMerged(app)
    if isempty(app.ImgPreview), return; end
    H = app.PreviewSize(1); W = app.PreviewSize(2);
    RGB = zeros(H,W,3,'double');
    for c=1:app.Nch
        if ~app.Disp(c).visible, continue; end
        I  = double(app.ImgPreview{c}) / double(intmax(class(app.ImgPreview{c})));
        Iw = (I - app.Disp(c).min) / max(1e-12,(app.Disp(c).max-app.Disp(c).min));
        Iw = min(max(Iw,0),1);
        RGB = max(RGB, colorize(app, Iw, app.Disp(c).lut));
    end

    if isempty(app.hImgMerge) || ~isvalid(app.hImgMerge)
        app.hImgMerge = image(app.UIAxes_2, RGB);
        axis(app.UIAxes_2,'image'); app.UIAxes_2.Visible='off';
    else
        app.hImgMerge.CData = RGB;
    end

    delete(findall(app.UIAxes_2,'Tag','roiOverlay'));  % <-- clear overlays only
    drawAllRoiOutlines(app, app.UIAxes_2);
end


        function Iw = applyWindow(app, I, lo, hi)
            %#ok<*INUSD>  % (optional) keep the warning quiet if you don't use app here
            Iw = im2double(mat2gray(I,[lo hi]));
        end

        function m = lutMap(app, lutName)
            switch lower(lutName)
                case 'red',     m = [linspace(0,1,256)' zeros(256,1) zeros(256,1)];
                case 'green',   m = [zeros(256,1) linspace(0,1,256)' zeros(256,1)];
                case 'blue',    m = [zeros(256,1) zeros(256,1) linspace(0,1,256)'];
                case 'magenta', m = [linspace(0,1,256)' zeros(256,1) linspace(0,1,256)'];
                case 'cyan',    m = [zeros(256,1) linspace(0,1,256)' linspace(0,1,256)'];
                case 'yellow',  m = [linspace(0,1,256)' linspace(0,1,256)' zeros(256,1)];
                otherwise,      m = gray(256);
            end
        end

        function rgb = colorize(app, I, lutName)
            I = im2double(I);
            switch lower(lutName)
                case 'red',     rgb = cat(3,I,0*I,0*I);
                case 'green',   rgb = cat(3,0*I,I,0*I);
                case 'blue',    rgb = cat(3,0*I,0*I,I);
                case 'magenta', rgb = cat(3,I,0*I,I);
                case 'cyan',    rgb = cat(3,0*I,I,I);
                case 'yellow',  rgb = cat(3,I,I,0*I);
                otherwise,      rgb = repmat(I,1,1,3);
            end
        end

        function idx = getSelectedChannelIndex(app)
            val = char(app.SelectedChannelDropDown.Value);
            idx = sscanf(val,'Ch %d');
            if isempty(idx), idx = 1; end
        end


        function startRoiDraw(app,mode)
            if isempty(app.ImgPreview), return; end
            ax = app.ActiveDrawAxes;
            if isempty(ax) || ~isvalid(ax)
                ax = app.UIAxes;  % fallback to Selected Channel
            end

            % remove any temp ROI
            if ~isempty(app.ActiveRoi) && isvalid(app.ActiveRoi)
                delete(app.ActiveRoi); app.ActiveRoi = [];
            end

            switch mode
                case 'freehand'
                    r = drawfreehand(ax,'InteractionsAllowed','all');
                    % Force closure (闭合)
                    if isprop(r,'Closed'), r.Closed = true; end

                case 'polygon'
                    r = drawpolygon(ax,'InteractionsAllowed','all');
                    % Ensure first and last vertices connect (闭合)
                    pos = r.Position;
                    if ~isequal(pos(1,:), pos(end,:))
                        r.Position = [pos; pos(1,:)];
                    end
            end

            % keep handle & a light fill so user sees it's closed
            r.FaceAlpha = 0.1; r.Color = 'y';
            app.ActiveRoi = r;
        end


        function editSelectedRoi(app)
            if isempty(app.ActiveRoi) || ~isvalid(app.ActiveRoi), return; end
            app.ActiveRoi.InteractionsAllowed = 'all';
        end

        function undoRoi(app)
            if ~isempty(app.ActiveRoi) && isvalid(app.ActiveRoi)
                delete(app.ActiveRoi); app.ActiveRoi = [];
                showSelectedChannel(app); showMerged(app);
            end
        end

        function deleteSelectedRoi(app)
            % table-selected delete
            sel = app.UITable.Selection;
            if isempty(sel), return; end
            row = sel(1);
            if row<=numel(app.RoiList)
                app.RoiList(row) = [];
                refreshTable(app);
                showSelectedChannel(app); showMerged(app);
            end
        end

        function commitRoi(app)
            if isempty(app.ActiveRoi) || ~isvalid(app.ActiveRoi)
                uialert(app.UIFigure,'Draw an ROI first.','No ROI'); return;
            end
            nm = strtrim(app.RegionNameEditField.Value);
            if isempty(nm), uialert(app.UIFigure,'Please enter a Region Name.','Missing name'); return; end

            % Ensure closed
            if isprop(app.ActiveRoi,'Closed'), app.ActiveRoi.Closed = true; end
            posPrev = app.ActiveRoi.Position;  % vertices in PREVIEW coordinates

            % A quick preview-area (nice for the table)
            maskPrev = createMask(app.ActiveRoi);
            umPerPixPrev = app.ScaleUmPerPix * app.PreviewStep;
            area_um2 = sum(maskPrev(:)) * (umPerPixPrev^2);

            % Store only vertices (preview space); full mask is generated on export
            app.RoiList(end+1) = struct( ...
                'id', app.NextRoiID, ...
                'name', nm, ...
                'vertices', posPrev, ...      % PREVIEW coordinates
                'area_um2', area_um2, ...
                'visible', true);

            app.NextRoiID = app.NextRoiID + 1;
            delete(app.ActiveRoi); app.ActiveRoi = [];
            app.RegionNameEditField.Value = '';
            refreshTable(app);
            showSelectedChannel(app); showMerged(app);
        end


        function drawAllRoiOutlines(app, ax)
    if isempty(app.RoiList), return; end
    for k = 1:numel(app.RoiList)
        r = app.RoiList(k);
        if ~r.visible || isempty(r.vertices), continue; end

        P = r.vertices;
        if ~isequal(P(1,:), P(end,:)), P = [P; P(1,:)]; end

        % Light fill (tag overlays so we can delete them cleanly)
        patch(ax, 'XData',P(:,1), 'YData',P(:,2), ...
            'FaceColor','y', 'FaceAlpha',0.07, ...
            'EdgeColor','none', 'HitTest','off','PickableParts','none', ...
            'Tag','roiOverlay');

        % Outline
        line(ax, P(:,1), P(:,2), ...
            'Color',[1 1 0], 'LineWidth',1.5, 'LineJoin','round', ...
            'Clipping','on', 'HitTest','off','PickableParts','none', ...
            'Tag','roiOverlay');

        % Label at polygon centroid
        try
            pg = polyshape(P);
            [cx,cy] = centroid(pg);
        catch
            cx = mean(P(:,1)); cy = mean(P(:,2));
        end
        text(ax, cx, cy, string(r.name), ...
            'Color','y','FontSize',9,'HorizontalAlignment','center', ...
            'Clipping','on','HitTest','off','Tag','roiOverlay');
    end
end


        function refreshTable(app)
            if isempty(app.RoiList)
                app.UITable.Data = cell(0,4);
            else
                T = cell(numel(app.RoiList),4);
                for k = 1:numel(app.RoiList)
                    T{k,1} = app.RoiList(k).id;
                    T{k,2} = app.RoiList(k).name;
                    T{k,3} = app.RoiList(k).area_um2;
                    T{k,4} = app.RoiList(k).visible;
                end
                app.UITable.Data = T;
                app.UITable.ColumnEditable = [false true false true];
            end
        end

        function onTableEdit(app,evt)
            row = evt.Indices(1); col = evt.Indices(2);
            if row>numel(app.RoiList), return; end
            switch col
                case 2, app.RoiList(row).name = string(evt.NewData);
                case 4, app.RoiList(row).visible = logical(evt.NewData);
            end
            showSelectedChannel(app); showMerged(app);
        end

        function onExport(app)
            if isempty(app.FilePath)
                uialert(app.UIFigure,'Import an image first.','No image'); return;
            end
            outDir = uigetdir(fileparts(app.FilePath),'Choose export folder');
            if isequal(outDir,0), return; end

            [~,base] = fileparts(app.FilePath);

            % 1) merged PNG
            showMerged(app); drawnow;
            pngPath = fullfile(outDir, [base '_Merged_ROIs.png']);
            exportgraphics(app.UIAxes_2, pngPath, 'Resolution', 200);

            % 2) masks (FULL-RES, cropped to ROI bbox)
            maskDir = fullfile(outDir, 'masked');
            if ~exist(maskDir,'dir'), mkdir(maskDir); end

            padPixels = 0;   % optional margin in FULL-RES pixels
            step = app.PreviewStep;

            for k = 1:numel(app.RoiList)
                r = app.RoiList(k);
                if isempty(r.vertices), continue; end
                roiName = app.safeName(r.name);

                % --- Build FULL-RES mask from scaled vertices ---
                % Scale preview vertices to full-res coordinates
                Vfull = r.vertices * step;
                % Make sure within image bounds
                Vfull(:,1) = min(max(Vfull(:,1),1), app.FullSize(2));
                Vfull(:,2) = min(max(Vfull(:,2),1), app.FullSize(1));

                maskFull = poly2mask(Vfull(:,1), Vfull(:,2), app.FullSize(1), app.FullSize(2));
                if ~any(maskFull(:)), continue; end

                % Tight bbox on FULL-RES mask
                [y1,y2,x1,x2] = app.tightRectFromMask(maskFull, padPixels);
                maskCrop = maskFull(y1:y2, x1:x2);

                % Multi-page TIFF per ROI, FULL-RES frames, cropped to bbox
                mpPath = fullfile(maskDir, sprintf('%02d_%s.tif', r.id, roiName));
                if exist(mpPath,'file'), delete(mpPath); end

                isMultiSample = isfield(app.FullInfo(1),'SamplesPerPixel') && app.FullInfo(1).SamplesPerPixel>1;
                firstFrameWritten = false;

                for c = 1:app.FullNch
                    % Read ONLY the bbox region for this channel from disk
                    region = {[y1 y2], [x1 x2]};

                    if isMultiSample
                        % Read one full page’s bbox then select channel
                        Icrop = imread(app.FilePath, 'Index', 1, 'PixelRegion', region);
                        if size(Icrop,3) >= c
                            Ic = Icrop(:,:,c);
                        else
                            Ic = Icrop(:,:,end); % safety fallback
                        end
                    else
                        Ic = imread(app.FilePath, c, 'Info', app.FullInfo, 'PixelRegion', region);
                    end

                    % Zero outside mask within bbox
                    Ic(~maskCrop) = 0;

                    frame = app.castForExport(double(Ic)/double(intmax(class(Ic))));
                    if ~firstFrameWritten
                        imwrite(frame, mpPath, 'Compression','none', 'WriteMode','overwrite');
                        firstFrameWritten = true;
                    else
                        imwrite(frame, mpPath, 'Compression','none', 'WriteMode','append');
                    end
                end
            end

            
            % 3) xlsx
            xlsxPath = '';
            if ~isempty(app.RoiList)
                T = table( ...
                    [app.RoiList.id]', string({app.RoiList.name})', [app.RoiList.area_um2]', logical([app.RoiList.visible]') , ...
                    'VariableNames',{'No','Name','Area_um2','Visible'});
                xlsxPath = fullfile(outDir, [base '_ROIs_Summary.xlsx']);
                writetable(T, xlsxPath, 'Sheet','ROIs');
                chans = (1:app.Nch)';
                MinMax = arrayfun(@(d) sprintf('[%.4f, %.4f]', d.min, d.max), app.Disp, 'uni',0)';
                LUTs   = string({app.Disp.lut})';
                Vis    = logical([app.Disp.visible])';
                TM = table(chans, LUTs, MinMax, Vis, 'VariableNames',{'Channel','LUT','MinMax','Visible'});
                writetable(TM, xlsxPath, 'Sheet','DisplayParams');
                M = table(string(app.FilePath), app.ScaleUmPerPix, app.ImgSize(2), app.ImgSize(1), ...
                    'VariableNames',{'SourceFile','UmPerPixel','Width','Height'});
                writetable(M, xlsxPath, 'Sheet','Meta');
            end

            % Message that adapts
            if isempty(xlsxPath)
                msg = sprintf('Exported:\n%s\n%s', pngPath, maskDir);
            else
                msg = sprintf('Exported:\n%s\n%s\n%s', pngPath, maskDir, xlsxPath);
            end
            uialert(app.UIFigure, msg, 'Done');
        end

        function J = castForExport(app, I)
            % I is double [0,1]; cast to original bit depth
            switch app.SourceBitsPerSample
                case 16
                    J = uint16(round(I * 65535));
                case 8
                    J = uint8(round(I * 255));
                otherwise
                    % Uncommon bit depth: keep double
                    J = I;
            end
        end
        
        function s = safeName(app, nameStr)
            s = char(regexprep(string(nameStr), '[^\w\- ]', '_')); % keep letters,digits,_,-,space
        end
        
        function setActiveAxes(app, ax)
            app.ActiveDrawAxes = ax;
            % Outline active axes
            app.UIAxes.Box = 'on';  app.UIAxes_2.Box = 'on';
            app.UIAxes.Color = [0 0 0]; app.UIAxes_2.Color = [0 0 0];
            if ax == app.UIAxes
                app.UIAxes.LineWidth = 1.5; app.UIAxes_2.LineWidth = 0.5;
            else
                app.UIAxes.LineWidth = 0.5; app.UIAxes_2.LineWidth = 1.5;
            end
        end

        function [y1,y2,x1,x2] = tightRectFromMask(app, mask, pad)
            % pad in pixels (optional)
            if nargin < 3, pad = 0; end
            if ~any(mask(:))
                % empty mask; return a 1x1 box at (1,1)
                y1 = 1; y2 = 1; x1 = 1; x2 = 1;
                return;
            end
            rows = find(any(mask,2));
            cols = find(any(mask,1));
            y1 = max(1, rows(1) - pad);
            y2 = min(size(mask,1), rows(end) + pad);
            x1 = max(1, cols(1) - pad);
            x2 = min(size(mask,2), cols(end) + pad);
        end

        function [IcCrop, maskCrop] = cropToRect(app, Ic, mask, y1,y2,x1,x2)
            IcCrop   = Ic(y1:y2, x1:x2);
            maskCrop = mask(y1:y2, x1:x2);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            onImport(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            onExport(app);
        end

        % Value changed function: SelectedChannelDropDown
        function SelectedChannelDropDownValueChanged(app, event)
            onSelectedChannelChanged(app);
        end

        % Value changed function: ColorLUTDropDown
        function ColorLUTDropDownValueChanged(app, event)
            onColorLUTChanged(app);
        end

        % Value changed function: ImageintensitySlider
        function ImageintensitySliderValueChanged(app, event)
            onIntensityChanged(app);
        end

        % Value changed function: EditFieldm2pixelUnit
        function EditFieldm2pixelUnitValueChanged(app, event)
            onScaleChanged(app);
        end

        % Value changed function: Ch1CheckBox
        function Ch1CheckBoxValueChanged(app, event)
            onChannelShowChanged(app,1);
        end

        % Value changed function: Ch2CheckBox
        function Ch2CheckBoxValueChanged(app, event)
            onChannelShowChanged(app,2); 
        end

        % Value changed function: Ch3CheckBox
        function Ch3CheckBoxValueChanged(app, event)
            onChannelShowChanged(app,3);
        end

        % Value changed function: Ch4CheckBox
        function Ch4CheckBoxValueChanged(app, event)
            onChannelShowChanged(app,4); 
        end

        % Button pushed function: FreehandButton
        function FreehandButtonPushed(app, event)
            startRoiDraw(app,'freehand');
        end

        % Button pushed function: PolygonButton
        function PolygonButtonPushed(app, event)
            startRoiDraw(app,'polygon');
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            editSelectedRoi(app);
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            undoRoi(app);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            deleteSelectedRoi(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            commitRoi(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            onTableEdit(app,event);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.setActiveAxes(app.UIAxes);
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            app.setActiveAxes(app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Selected Channel')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Merged Channel')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'No.'; 'Name'; 'Area (µm²)'; 'Visible'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.FilePanel = uipanel(app.UIFigure);
            app.FilePanel.Title = 'File';
            app.ImportButton = uibutton(app.FilePanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.FilePanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImageprocessPanel = uipanel(app.UIFigure);
            app.ImageprocessPanel.Title = 'Image process';
            app.SelectedChannelDropDownLabel = uilabel(app.ImageprocessPanel);
            app.SelectedChannelDropDownLabel.Text = 'Selected Channel';
            app.SelectedChannelDropDown = uidropdown(app.ImageprocessPanel);
            app.SelectedChannelDropDown.Items = {'Ch 1', 'Ch 2', 'Ch 3', 'Ch 4'};
            app.SelectedChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedChannelDropDownValueChanged, true);
            app.SelectedChannelDropDown.Value = 'Ch 1';
            app.RatioLabel = uilabel(app.ImageprocessPanel);
            app.RatioLabel.Text = 'Ratio';
            app.mpixelEditFieldLabel = uilabel(app.ImageprocessPanel);
            app.mpixelEditFieldLabel.Text = 'μm/pixel';
            app.EditFieldm2pixelUnit = uieditfield(app.ImageprocessPanel, 'numeric');
            app.EditFieldm2pixelUnit.ValueChangedFcn = createCallbackFcn(app, @EditFieldm2pixelUnitValueChanged, true);
            app.EditFieldm2pixelUnit.Value = 0.65;
            app.ImageintensitySliderLabel = uilabel(app.ImageprocessPanel);
            app.ImageintensitySliderLabel.Text = 'Image intensity';
            app.ImageintensitySlider = uislider(app.ImageprocessPanel);
            app.ImageintensitySlider.Limits = [0 1];
            app.ImageintensitySlider.ValueChangedFcn = createCallbackFcn(app, @ImageintensitySliderValueChanged, true);
            app.ImageintensitySlider.Value = 0.1;
            app.ChannelShowPanel = uipanel(app.ImageprocessPanel);
            app.ChannelShowPanel.Title = 'Channel Show';
            app.Ch1CheckBox = uicheckbox(app.ChannelShowPanel);
            app.Ch1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch1CheckBoxValueChanged, true);
            app.Ch1CheckBox.Text = 'Ch1';
            app.Ch2CheckBox = uicheckbox(app.ChannelShowPanel);
            app.Ch2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch2CheckBoxValueChanged, true);
            app.Ch2CheckBox.Text = 'Ch2';
            app.Ch3CheckBox = uicheckbox(app.ChannelShowPanel);
            app.Ch3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch3CheckBoxValueChanged, true);
            app.Ch3CheckBox.Text = 'Ch3';
            app.Ch4CheckBox = uicheckbox(app.ChannelShowPanel);
            app.Ch4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch4CheckBoxValueChanged, true);
            app.Ch4CheckBox.Text = 'Ch4';
            app.ColorLUTDropDownLabel = uilabel(app.ImageprocessPanel);
            app.ColorLUTDropDownLabel.Text = 'Color LUT';
            app.ColorLUTDropDown = uidropdown(app.ImageprocessPanel);
            app.ColorLUTDropDown.Items = {'Blue', 'Green', 'Red', 'Grey', 'Magenta', 'Cyan', 'Yellow'};
            app.ColorLUTDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorLUTDropDownValueChanged, true);
            app.ColorLUTDropDown.Value = 'Blue';
            app.SegmentandAnnotationPanel = uipanel(app.UIFigure);
            app.SegmentandAnnotationPanel.Title = 'Segment and Annotation';
            app.RegionNameEditFieldLabel = uilabel(app.SegmentandAnnotationPanel);
            app.RegionNameEditFieldLabel.Text = 'Region Name';
            app.RegionNameEditField = uieditfield(app.SegmentandAnnotationPanel, 'text');
            app.AddButton = uibutton(app.SegmentandAnnotationPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.FreehandButton = uibutton(app.SegmentandAnnotationPanel, 'push');
            app.FreehandButton.ButtonPushedFcn = createCallbackFcn(app, @FreehandButtonPushed, true);
            app.FreehandButton.Text = 'Freehand';
            app.ROItoolsLabel = uilabel(app.SegmentandAnnotationPanel);
            app.ROItoolsLabel.Text = 'ROI tools';
            app.PolygonButton = uibutton(app.SegmentandAnnotationPanel, 'push');
            app.PolygonButton.ButtonPushedFcn = createCallbackFcn(app, @PolygonButtonPushed, true);
            app.PolygonButton.Text = 'Polygon';
            app.EditButton = uibutton(app.SegmentandAnnotationPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeleteButton = uibutton(app.SegmentandAnnotationPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.UndoButton = uibutton(app.SegmentandAnnotationPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Segment_Annotation_V1_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end